classdef analysisAD < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure                   % UI Figure
        TabGroup               matlab.ui.container.TabGroup       % Prepari...
        prep_tab               matlab.ui.container.Tab            % Preparing
        UIAxes1                matlab.ui.control.UIAxes           % Title
        init                   matlab.ui.container.Panel          % Initial...
        LabelSlider            matlab.ui.control.Label            % Begin
        cutBegin               matlab.ui.control.Slider           % [1 100]
        Label                  matlab.ui.control.Label            % End
        cutEnd                 matlab.ui.control.Slider           % [0 100]
        setP                   matlab.ui.control.Button           % Set
        findk                  matlab.ui.control.Button           % Find K's
        findpulse              matlab.ui.control.Button           % Find pu...
        qvstime                matlab.ui.control.Button           % SeeQ
        pulsenumtext           matlab.ui.control.TextArea        
        qends                  matlab.ui.control.Button           % Qends
        LabelNumericEditField4 matlab.ui.control.Label            % Poly de...
        polydegree             matlab.ui.control.NumericEditField % [1 10]
        applypoly              matlab.ui.control.Button           % Apply poly
        OpenFile               matlab.ui.control.Button           % open file
        LabelNumericEditField6 matlab.ui.control.Label            % SFluid ...
        sfluid                 matlab.ui.control.NumericEditField % [0 1]
        LabelTextArea          matlab.ui.control.Label            % K values
        textfork               matlab.ui.control.TextArea         % K_T = 0
        LabelEditField         matlab.ui.control.Label            % Folder ...
        path                   matlab.ui.control.EditField        % e:\repo...
        tempSF                 matlab.ui.control.Button           % Tempera...
        filtering_tab          matlab.ui.container.Tab            % Filtering
        UIAxes2                matlab.ui.control.UIAxes           % Title
        Panel                  matlab.ui.container.Panel          % Pulse s...
        LabelNumericEditField  matlab.ui.control.Label            % Pulse n...
        pulsenum               matlab.ui.control.NumericEditField % [0 Inf]
        LabelDropDown          matlab.ui.control.Label            % Filters
        filt                   matlab.ui.control.DropDown         % Median,...
        LabelNumericEditField2 matlab.ui.control.Label            % 1st fie...
        filt_n1                matlab.ui.control.NumericEditField % [0 Inf]
        apply1                 matlab.ui.control.Button           % Apply f...
        incline                matlab.ui.control.Button           % Incline
        LabelSlider2           matlab.ui.control.Label            % Begin
        cutBegin2              matlab.ui.control.Slider           % [1 100]
        Label2                 matlab.ui.control.Label            % End
        cutEnd2                matlab.ui.control.Slider           % [1 100]
        apply_cut              matlab.ui.control.Button           % Apply cut
        fitting_tab            matlab.ui.container.Tab            % Fitting
        UIAxes3                matlab.ui.control.UIAxes           % Title
        Panel2                 matlab.ui.container.Panel          % Filter ...
        LabelDropDown2         matlab.ui.control.Label            % Fitting...
        fitfuns                matlab.ui.control.DropDown         % Fit1, F...
        Label3                 matlab.ui.control.Label            % Fit 1: ...
        Label4                 matlab.ui.control.Label            % Fit 2: ...
        Label5                 matlab.ui.control.Label            % Fit 3: ...
        exportF                matlab.ui.control.Button           % Export ...
        UIAxes4                matlab.ui.control.UIAxes           % Title
        Panel3                 matlab.ui.container.Panel          % Fitting...
        printfit               matlab.ui.control.TextArea         % 1
        resid_tab              matlab.ui.container.Tab            % Residuals
        UIAxes5                matlab.ui.control.UIAxes           % Residuals
        UIAxes6                matlab.ui.control.UIAxes           % Title
        errtext                matlab.ui.control.TextArea         % res_n
        LabelNumericEditField3 matlab.ui.control.Label            % Bins
        nbin                   matlab.ui.control.NumericEditField % [1 Inf]
        clear_err              matlab.ui.control.Button           % Clear
        Closeapp               matlab.ui.control.Button           % Close
        LabelNumericEditField5 matlab.ui.control.Label            % Edit Field
        k_f2                   matlab.ui.control.NumericEditField % [-Inf Inf]
        remodell_tab           matlab.ui.container.Tab            % Re-model
        UIAxes7                matlab.ui.control.UIAxes           % Re-model
        LabelTextArea2         matlab.ui.control.Label            % Data fo...
        datafolder             matlab.ui.control.TextArea         % path
    end


    properties (Access = public)
        ff; % import data function
        ff1; % data after cutting
        beg1; % cut begin in ini
        end1; % cut form end in ini
        beg2; % cut after filtering
        end2;
        c; % indecies of begin and end pulse
        pulse_one; % Description
        n_pul; % number of pulse
        af; % filtered y axis
        f_inc; % data after incline
        k_q; %incline of Q data
        b_q;
        tim; % time after filtering
        tem; % temperature of pulse
        fit1; % fit 1 parametrs
        fit2; % fit 2 parameters
        fit3;
        fit4;
        poly_off; 
        ruta;
        full_pulse;
        tx;
        ty;
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.path.Value=pwd;
            [file,app.ruta]=uigetfile({'*.dat'},'Select a dat file for second Fork');
            cd(app.ruta);
            ff2=importdata(file);
            [b1,~]=size(ff2.data);
            if b1>1
                app.ff=ff2.data;
            else
                app.ff=str2double(ff2.textdata(2:end,2:end));
            end
            app.ff(:,1)=app.ff(:,1)-app.ff(1,1);
            app.ff(:,end)=[];
            %% initial parameters
            cd(app.path.Value)
            app.end1=length(app.ff);
            app.beg1=1;
            app.cutBegin.Limits = [1 app.end1];
            app.cutEnd.Limits = [1 app.end1];
            app.cutEnd.Value = app.end1;
            app.ff1=app.ff;
            Pkb=[0 0];
            app.fit2=[1 0 1000 0];
            app.b_q=0;
            p_n=1;
            %% plot temperature vs time
                        
            app.UIAxes1.cla;
            plot(app.UIAxes1,app.ff(:,1),app.ff(:,12));
            title(app.UIAxes1, 'Print after import');
            xlabel(app.UIAxes1, 'Time [sec]');
            ylabel(app.UIAxes1, 'Temperature [mK]');
            
            mintime=1;
            maxtime=length(app.ff);

        end

        % cutBegin value changed function
        function cutBeginValueChanged(app)
            app.beg1 = round(app.cutBegin.Value);
            app.UIAxes1.cla;
            plot(app.UIAxes1,app.ff(app.beg1:app.end1,1),app.ff(app.beg1:app.end1,12));
            title(app.UIAxes1, 'Modifying');
            xlabel(app.UIAxes1, 'Time [sec]');
            ylabel(app.UIAxes1, 'Temperature [mK]');
        end

        % cutEnd value changed function
        function cutEndValueChanged(app)
            app.end1 = round(app.cutEnd.Value);
            app.UIAxes1.cla;
            plot(app.UIAxes1,app.ff(app.beg1:app.end1,1),app.ff(app.beg1:app.end1,12));
            title(app.UIAxes1, 'Modifying');
            xlabel(app.UIAxes1, 'Time [sec]');
            ylabel(app.UIAxes1, 'Temperature [mK]');
        end

        % setP button pushed function
        function setPButtonPushed(app)
            app.ff1=app.ff;
            app.ff1(app.end1:end,:)=[];
            app.ff1(1:app.beg1,:)=[];
            app.UIAxes1.cla;
            plot(app.UIAxes1,app.ff1(:,1),app.ff1(:,12));
            title(app.UIAxes1, 'Modifyed');
            xlabel(app.UIAxes1, 'Time [sec]');
            ylabel(app.UIAxes1, 'Temperature [mK]');
            ylim(app.UIAxes1,'auto');
            app.datafolder.Value = {app.ruta};
            
        end

        % qvstime button pushed function
        function qvstimeButtonPushed(app)
            app.UIAxes1.cla;
            plot(app.UIAxes1,app.ff1(:,1),app.ff1(:,5));
            title(app.UIAxes1, 'Modifyed');
            xlabel(app.UIAxes1, 'Time [sec]');
            ylabel(app.UIAxes1, 'Temperature [mK]');
        end

        % findk button pushed function
        function findkButtonPushed(app)
            Pkb = polyfit(app.ff1(:,1),app.ff1(:,12),1);
            x1=mean(app.ff1(1:100,1));
            x2=mean(app.ff1(end-100:end,1));
            y1=mean(app.ff1(1:100,5));
            y2=mean(app.ff1(end-100:end,5));
            app.k_q=(y2-y1)/(x2-x1);
            st1=num2str(Pkb(1));
            st=['K_T is ',st1];
            st2=num2str(app.k_q);
            stt=['K_Q is ',st2];
            app.textfork.Value = {st; stt};
        end

        % findpulse button pushed function
        function findpulseButtonPushed(app)
            app.c=pulse_finder(app.ff1,10);
            [c1,~]=size(app.c);
            app.pulsenum.Limits = [1 c1];
            st1=num2str(c1);
            app.pulsenumtext.Value={st1};
            app.UIAxes1.cla;
            hold(app.UIAxes1,'on');
            plot(app.UIAxes1,app.ff1(:,1),app.ff1(:,5));
            plot(app.UIAxes1,app.ff1(app.c(:,1),1),app.ff1(app.c(:,1),5),'or');
        end

        % pulsenum value changed function
        function pulsenumValueChanged(app)
            app.n_pul = round(app.pulsenum.Value);
            st1=num2str(app.n_pul);
            app.tem=num2str(app.ff1(app.c(app.n_pul,1),12));
            st=['Unfiltered ',st1,'s pulse; T = ',app.tem,' mK'];           
            app.UIAxes2.cla;
            plot(app.UIAxes2,app.ff1(app.c(app.n_pul,1):app.c(app.n_pul,2),1),app.ff1(app.c(app.n_pul,1):app.c(app.n_pul,2),5));
            
            title(app.UIAxes2,st);
            xlabel(app.UIAxes2,'Time [sec]');
            ylabel(app.UIAxes2,'Q');
        end

        % filt value changed function
        function filtValueChanged(app, event)
            val = app.filt.Value;
            switch val
                case 'Median'
                    %app.af=abs(medfilt1(app.ff1(app.c(app.n_pul,1):app.c(app.n_pul,2),5),app.pulse_one));
                    app.af=medfilt1(app.ff1(app.c(app.n_pul,1):app.c(app.n_pul,2),5),app.pulse_one);
                    app.af(1)=app.af(2);
                    app.UIAxes2.cla;
                    hold(app.UIAxes2,'on');
                    plot(app.UIAxes2,app.ff1(app.c(app.n_pul,1):app.c(app.n_pul,2),1),app.ff1(app.c(app.n_pul,1):app.c(app.n_pul,2),5));
                    plot(app.UIAxes2,app.ff1(app.c(app.n_pul,1):app.c(app.n_pul,2),1),app.af,'-r','LineWidth',1);
                    title(app.UIAxes2,'Median');
                    xlabel(app.UIAxes2,'Time [sec]');
                    ylabel(app.UIAxes2,'Q');
                    app.tim=app.ff1(app.c(app.n_pul,1):app.c(app.n_pul,2),1);
                case 'None'
                    st1=num2str(app.n_pul);
                    app.tem=num2str(app.ff1(app.c(app.n_pul,1),12));
                    st=['Unfiltered ',st1,'s pulse; T = ',app.tem,' mK'];   
                    app.UIAxes2.cla;
                    plot(app.UIAxes2,app.ff1(app.c(app.n_pul,1):app.c(app.n_pul,2),1),app.ff1(app.c(app.n_pul,1):app.c(app.n_pul,2),5));
                    title(app.UIAxes2,st);
                    xlabel(app.UIAxes2,'Time [sec]');
                    ylabel(app.UIAxes2,'Q');
                    app.tim=app.ff1(app.c(app.n_pul,1):app.c(app.n_pul,2),1);
                case 'Moving'
                    b=(1/app.pulse_one)*ones(1,app.pulse_one);
                    a=1;
                    app.af=filter(b,a,app.ff1(app.c(app.n_pul,1):app.c(app.n_pul,2),5));
                    app.af(1:app.pulse_one-1)=app.af(app.pulse_one);
                    app.UIAxes2.cla;
                    hold(app.UIAxes2,'on');
                    plot(app.UIAxes2,app.ff1(app.c(app.n_pul,1):app.c(app.n_pul,2),1),app.ff1(app.c(app.n_pul,1):app.c(app.n_pul,2),5));
                    plot(app.UIAxes2,app.ff1(app.c(app.n_pul,1):app.c(app.n_pul,2),1),app.af,'-r','LineWidth',1);
                    title(app.UIAxes2,'Moving');
                    xlabel(app.UIAxes2,'Time [sec]');
                    ylabel(app.UIAxes2,'Q');
                    app.tim=app.ff1(app.c(app.n_pul,1):app.c(app.n_pul,2),1);
            end
            
        end

        % filt_n1 value changed function
        function filt_n1ValueChanged(app)
            app.pulse_one = round(app.filt_n1.Value);
            
        end

        % apply1 button pushed function
        function apply1ButtonPushed(app)
            app.filtValueChanged;
        end

        % incline button pushed function
        function inclineButtonPushed(app)
            for ii=1:length(app.af)
                app.f_inc(ii,1)=app.ff1(app.c(app.n_pul,1)+ii-1,1);
                app.full_pulse(ii,1)=app.ff1(app.c(app.n_pul,1)+ii-1,1);
                %app.f_inc(ii,2)= app.af(ii)-app.k_q*app.f_inc(ii,1);
                app.f_inc(ii,2)= app.af(ii);
                app.full_pulse(ii,2)=app.af(ii);
            end
            app.cutBegin2.Limits = [1 length(app.f_inc)];
            app.cutEnd2.Limits = [1 length(app.f_inc)];
            app.cutEnd2.Value = length(app.f_inc);
            app.UIAxes2.cla;
            plot(app.UIAxes2,app.f_inc(:,1),app.f_inc(:,2),'-r','LineWidth',1);
            title(app.UIAxes2,'After incline');
            xlabel(app.UIAxes2,'Time [sec]');
            ylabel(app.UIAxes2,'Q');
            %app.full_pulse=app.f_inc;
        end

        % cutBegin2 value changed function
        function cutBegin2ValueChanged(app)
            app.beg2 = round(app.cutBegin2.Value);
            app.UIAxes2.cla;
            plot(app.UIAxes2,app.f_inc(app.beg2:app.end2,1),app.f_inc(app.beg2:app.end2,2));
            title(app.UIAxes2, 'Modifying');
            xlabel(app.UIAxes2, 'Time [sec]');
            ylabel(app.UIAxes2, 'Q');
        end

        % cutEnd2 value changed function
        function cutEnd2ValueChanged(app)
            app.end2 = round(app.cutEnd2.Value);
            app.UIAxes2.cla;
            plot(app.UIAxes2,app.f_inc(app.beg2:app.end2,1),app.f_inc(app.beg2:app.end2,2));
            title(app.UIAxes2, 'Modifying');
            xlabel(app.UIAxes2, 'Time [sec]');
            ylabel(app.UIAxes2, 'Q');
        end

        % Closeapp button pushed function
        function CloseappButtonPushed(app)
            delete(app);
        end

        % apply_cut button pushed function
        function apply_cutButtonPushed(app)
            %clear app.tx app.ty;
            app.full_pulse(app.beg2:end,:)=[];
            app.full_pulse(1:round(app.beg2*0.2),:)=[];
            round(app.beg2*0.66)
            [b1,z2]=size(app.full_pulse);
            app.tx=zeros(b1,1);
            app.ty=zeros(b1,1);
            app.tx(:,1)=app.full_pulse(:,1);
            app.ty(:,1)=app.full_pulse(:,2);
            app.tx(:,1)=app.tx(:,1)-app.tx(1);
            app.tim(app.end2:end,:)=[];
            app.tim(1:app.beg2,:)=[];
            app.af(app.end2:end,:)=[];
            app.af(1:app.beg2,:)=[];
            app.f_inc(app.end2:end,:)=[];
            app.f_inc(1:app.beg2,:)=[];
            app.f_inc(:,1)=app.f_inc(:,1)-app.f_inc(1,1);
            %app.f_inc(:,2)=abs(app.f_inc(:,2));
            app.f_inc(:,2)=app.f_inc(:,2);
            m1=min(app.f_inc(:,2));
            app.b_q=m1;
            app.tim(:)=app.tim(:)-app.tim(1);
            y2(:)=app.k_q.*app.tim(:)+mean(app.af(end-100:end));
            y2(:)=abs(y2(:)-(y2(end)-app.af(end)));
            app.UIAxes2.cla;
            hold(app.UIAxes2,'on');
            plot(app.UIAxes2,app.f_inc(:,1),app.f_inc(:,2));
            plot(app.UIAxes2,app.tim,app.af);
            plot(app.UIAxes2,app.tim,y2);
            title(app.UIAxes2, 'Modified');
            xlabel(app.UIAxes2, 'Time [sec]');
            ylabel(app.UIAxes2, 'Q');
        end

        % fitfuns value changed function
        function fitfunsValueChanged(app, event)
            val = app.fitfuns.Value;
            switch val
                case 'Fit1'
                    % fit
                    m1=min(app.f_inc(:,2));
                    %m1=0;
                    app.f_inc(:,2)=app.f_inc(:,2)-m1;
                    x0=[1 0 1000 0.1];
                    options = optimoptions(@fminunc,'MaxFunEvals',5000);
                    lb = [-400 -100 0 0];
                    ub = [400 100 400000 400];
                    [app.fit1,resnorm,residuals] = lsqcurvefit(@expfitfunUI,x0,app.f_inc(:,1)',app.f_inc(:,2)',lb,ub,options);
                    % text fittning parametrs
                    s1=num2str(app.fit1(1));
                    s2=num2str(app.fit1(2));
                    s3=num2str(app.fit1(3));
                    s4=num2str(app.fit1(4));
                    s5=num2str(app.tem);
                    s6=num2str(resnorm);
                    ss1=['A = ',s1];
                    ss2=['t_0 = ',s2];
                    ss3=['tau = ',s3];
                    ss4=['b = ',s4];
                    ss5=['Temperature = ',s5];
                    ss6=['Res Norm = ',s6];
                    app.printfit.Value = {ss1;ss2;ss3;ss4;ss5;ss6};
                    y=expfitfunUI(app.fit1,app.f_inc(:,1)');
                    app.UIAxes3.cla;
                    hold(app.UIAxes3,'on');
                    plot(app.UIAxes3,app.f_inc(:,1),app.f_inc(:,2));
                    plot(app.UIAxes3,app.f_inc(:,1),y);
                    title(app.UIAxes3, 'Fit1');
                    xlabel(app.UIAxes3, 'Time [sec]');
                    ylabel(app.UIAxes3, 'Q');
                    app.UIAxes4.cla;
                    hold(app.UIAxes4,'on');
                    plot(app.UIAxes4,app.f_inc(:,1),log(app.f_inc(:,2)));
                    plot(app.UIAxes4,app.f_inc(:,1),log(y));
                    title(app.UIAxes4, ' Log of Fit1');
                    xlabel(app.UIAxes4, 'Time [sec]');
                    ylabel(app.UIAxes4, 'ln(Q)');
                    app.UIAxes5.cla;
                    plot(app.UIAxes5,1:length(residuals),residuals);
                    [a,bc,rod,h,p]=errans(y,app.f_inc(:,2)',0,residuals,app.nbin.Value);
                   
                    %hold(app.UIAxes6,'on');
                    
                    plot(app.UIAxes6,bc,rod,'-xk');
                    pd = fitdist(residuals','Normal');
                    fgaus = fit(bc,rod,'gauss1');
                    y_gaus(:)=fgaus.a1.*exp(-((bc(:)-fgaus.b1)/fgaus.c1).^2);
                    plot(app.UIAxes6,bc,y_gaus,'--k');
                    sa6=num2str(fgaus.a1);
                    sa7=num2str(fgaus.b1);
                    sa8=num2str(fgaus.c1);
                    saa6=['A_gaus = ',sa6];
                    saa7=['mu_gaus = ',sa7];
                    saa8=['signma_gaus = ',sa8];
                    sa1=num2str(a);
                    sa2=num2str(h);
                    sa3=num2str(p);
                    sa4=num2str(pd.mu);
                    sa5=num2str(pd.sigma);
                    saa1=['chi2 = ',sa1];
                    saa2=['h = ',sa2];
                    saa3=['p = ',sa3];
                    saa4=['mu = ',sa4];
                    saa5=['sigma = ',sa5];
                    app.errtext.Value = {ss6;saa1;saa2;saa3;saa4;saa5;saa6;saa7;saa8};
                    [yfit,xfit,nb,ne]=remodel(y,m1,app.tim,app.sfluid.Value,app.beg2,app.ff,app.poly_off,app.c(app.n_pul,1),app.c(app.n_pul,2),app.beg1);
                    app.UIAxes7.cla;
                    hold(app.UIAxes7,'on');
                    plot(app.UIAxes7,app.ff(nb:ne,1),app.ff(nb:ne,5),'-b');
                    plot(app.UIAxes7,xfit,yfit,'r');
                case 'None'
                    ly(:)=log(app.f_inc(:,2));
                    app.UIAxes3.cla;
                    plot(app.UIAxes3,app.f_inc(:,1),app.f_inc(:,2));                    
                    title(app.UIAxes3, 'None');
                    xlabel(app.UIAxes3, 'Time [sec]');
                    ylabel(app.UIAxes3, 'Q');
                    % log
                    app.UIAxes4.cla;
                    plot(app.UIAxes4,app.f_inc(:,1),ly(:));                    
                    title(app.UIAxes4, 'Log');
                    xlabel(app.UIAxes4, 'Time [sec]');
                    ylabel(app.UIAxes4, 'ln(Q)');
                     
                case 'Fit2'
                    m1=min(app.f_inc(:,2));
                    %m1=0;
                    app.f_inc(:,2)=app.f_inc(:,2)-m1;
                    x0=[app.b_q app.k_q app.fit1(3) 0];
                    if app.polydegree.Value == 1
                        lb = [-10 -app.k_f2.Value 0 -40000];
                        ub = [10 app.k_f2.Value 400000 40000];
                    else
                        lb = [-10 -app.k_f2.Value 0 -40000];
                        ub = [10 app.k_f2.Value 400000 40000];
                    end
                    options = optimoptions(@fminunc,'MaxFunEvals',5000);
%                     lb = [-4000 0 0 -40000];
%                     ub = [4000 1 400000 40000];
                    [app.fit2,resnorm,residuals] = lsqcurvefit(@exp2fitfunUI,x0,app.tim',app.af',lb,ub,options);
                    aa=app.fit2;
                    save('fit2.mat','aa');
                    y1(:)=app.af(:)-app.fit2(2).*app.tim(:)-app.fit2(1); % substract
                    y2(:)=app.fit2(2).*app.tim(:)+app.fit2(1);
                    s1=num2str(app.fit2(1));
                    s2=num2str(app.fit2(2));
                    s3=num2str(app.fit2(3));
                   s4=num2str(app.fit2(4));
                    s5=num2str(app.tem);
                    s6=num2str(resnorm);
                    ss1=['A = ',s1];
                    ss2=['B = ',s2];
                    ss3=['tau = ',s3];
                    ss4=['t0 = ',s4];
                    ss5=['Temperature = ',s5];
                    ss6=['Res Norm = ',s6];
                    app.printfit.Value = {ss1;ss2;ss3;ss4;ss5;ss6};
                    y=exp2fitfunUI(app.fit2,app.tim');
                    app.UIAxes3.cla;
                    hold(app.UIAxes3,'on');
                    plot(app.UIAxes3,app.tim,app.af);
                    plot(app.UIAxes3,app.tim,y);
                    plot(app.UIAxes3,app.tim,y2);
                    title(app.UIAxes3, 'Fit2');
                    xlabel(app.UIAxes3, 'Time [sec]');
                    ylabel(app.UIAxes3, 'Q');
                    
                    app.UIAxes4.cla;
                    hold(app.UIAxes4,'on');
                    plot(app.UIAxes4,app.f_inc(:,1),app.f_inc(:,2));
                    plot(app.UIAxes4,app.tim,y1);
                    title(app.UIAxes4, ' Log of Fit1');
                    xlabel(app.UIAxes4, 'Time [sec]');
                    ylabel(app.UIAxes4, 'Q');
                    app.UIAxes5.cla;
                    plot(app.UIAxes5,1:length(residuals),residuals);
                    
                    [a,bc,rod,h,p]=errans(y,app.af',5,residuals,app.nbin.Value);
                    
                    %hold(app.UIAxes6,'on');
                    plot(app.UIAxes6,bc,rod,'-xr');
                    pd = fitdist(residuals','Normal');
                    fgaus = fit(bc,rod,'gauss1');
                    y_gaus(:)=fgaus.a1.*exp(-((bc(:)-fgaus.b1)/fgaus.c1).^2);
                    plot(app.UIAxes6,bc,y_gaus,'--r');
                    sa6=num2str(fgaus.a1);
                    sa7=num2str(fgaus.b1);
                    sa8=num2str(fgaus.c1);
                    saa6=['A_gaus = ',sa6];
                    saa7=['mu_gaus = ',sa7];
                    saa8=['signma_gaus = ',sa8];
                    sa1=num2str(a);
                    sa2=num2str(h);
                    sa3=num2str(p);
                    sa4=num2str(pd.mu);
                    sa5=num2str(pd.sigma);
                    saa1=['chi2 = ',sa1];
                    saa2=['h = ',sa2];
                    saa3=['p = ',sa3];
                    saa4=['mu = ',sa4];
                    saa5=['sigma = ',sa5];
                    app.errtext.Value = {ss6;saa1;saa2;saa3;saa4;saa5;saa6;saa7;saa8};
                    [yfit,xfit,nb,ne]=remodel(y,m1,app.tim,app.sfluid.Value,app.beg2,app.ff,app.poly_off,app.c(app.n_pul,1),app.c(app.n_pul,2),app.beg1);
                    app.UIAxes7.cla;
                    hold(app.UIAxes7,'on');
                    plot(app.UIAxes7,app.ff(nb:ne,1),app.ff(nb:ne,5),'-b');
                    plot(app.UIAxes7,xfit,yfit,'r');
                    x_orig=app.ff(nb:ne,1);
                    y_orig=app.ff(nb:ne,5);
                    size(xfit);
                    size(yfit);
                    z_orig = [x_orig y_orig];
                    z_fit=[xfit yfit'];
                    size(z_orig);
                    size(z_fit);
                    fileID = fopen('file1.txt','w');
                    fprintf(fileID,'%f \t %f\n',z_orig');                    
                    fclose(fileID);
                    fileID1 = fopen('file2.txt','w');
                    fprintf(fileID1,'%f \t %f\n',z_fit');                    
                    fclose(fileID1);
                case 'Fit3'
                    m1=min(app.f_inc(:,2));
                    app.f_inc(:,2)=app.f_inc(:,2)-m1;
                    x0=[app.fit2(1) app.fit2(2) app.fit2(3) app.fit2(4) 0];
                    options = optimoptions(@fminunc,'MaxFunEvals',5000);
                    lb=[0 -1000 0 -40000 0];
                    ub=[40000 1000 40000 40000 1000];
                    [app.fit3,resnorm,residuals] = lsqcurvefit(@exp3fitfunUI,x0,app.tim',app.af',lb,ub,options);
                    y1(:)=app.af(:)-app.fit3(2).*app.tim(:)-app.fit3(1); % substract
                    y2(:)=app.fit3(2).*app.tim(:)+app.fit3(1);
                    taue=app.fit3(3)+app.fit3(5)*app.tim(end);
                    s1=num2str(app.fit3(1));
                    s2=num2str(app.fit3(2));
                    s3=num2str(app.fit3(3));
                   s4=num2str(app.fit3(4));
                    s5=num2str(app.tem);
                    s6=num2str(resnorm);
                    s7=num2str(app.fit3(5));
                    s8=num2str(taue);
                    ss1=['A = ',s1];
                    ss2=['B = ',s2];
                    ss3=['tau = ',s3];
                    ss4=['t0 = ',s4];
                    ss7=['C = ',s7];
                    ss8=['tau_e = ',s8];
                    ss5=['Temperature = ',s5];
                    ss6=['Res Norm = ',s6];
                    app.printfit.Value = {ss1;ss2;ss3;ss4;ss7;ss8;ss5;ss6};
                    y=exp3fitfunUI(app.fit3,app.tim');
                    app.UIAxes3.cla;
                    hold(app.UIAxes3,'on');
                    plot(app.UIAxes3,app.tim,app.af);
                    plot(app.UIAxes3,app.tim,y);
                    plot(app.UIAxes3,app.tim,y2);
                    title(app.UIAxes3, 'Fit3');
                    xlabel(app.UIAxes3, 'Time [sec]');
                    ylabel(app.UIAxes3, 'Q');
                    
                    app.UIAxes4.cla;
                    hold(app.UIAxes4,'on');
                    plot(app.UIAxes4,app.f_inc(:,1),app.f_inc(:,2));
                    plot(app.UIAxes4,app.tim,y1);
                    title(app.UIAxes4, ' Log of Fit1');
                    xlabel(app.UIAxes4, 'Time [sec]');
                    ylabel(app.UIAxes4, 'Q');
                    app.UIAxes5.cla;
                    plot(app.UIAxes5,1:length(residuals),residuals);
                    
                    [a,bc,rod,h,p]=errans(y,app.af',5,residuals,app.nbin.Value);
                    
                    %hold(app.UIAxes6,'on');
                    plot(app.UIAxes6,bc,rod,'-xg','LineWidth',1);
                    pd = fitdist(residuals','Normal');
                    fgaus = fit(bc,rod,'gauss1');
                    y_gaus(:)=fgaus.a1.*exp(-((bc(:)-fgaus.b1)/fgaus.c1).^2);
                    plot(app.UIAxes6,bc,y_gaus,'--g');
                    sa6=num2str(fgaus.a1);
                    sa7=num2str(fgaus.b1);
                    sa8=num2str(fgaus.c1);
                    saa6=['A_gaus = ',sa6];
                    saa7=['mu_gaus = ',sa7];
                    saa8=['signma_gaus = ',sa8];
                    sa1=num2str(a);
                    sa2=num2str(h);
                    sa3=num2str(p);
                    sa4=num2str(pd.mu);
                    sa5=num2str(pd.sigma);
                    saa1=['chi2 = ',sa1];
                    saa2=['h = ',sa2];
                    saa3=['p = ',sa3];
                    saa4=['mu = ',sa4];
                    saa5=['sigma = ',sa5];
                    app.errtext.Value = {ss6;saa1;saa2;saa3;saa4;saa5;saa6;saa7;saa8};
                    [yfit,xfit,nb,ne]=remodel(y,m1,app.tim,app.sfluid.Value,app.beg2,app.ff,app.poly_off,app.c(app.n_pul,1),app.c(app.n_pul,2),app.beg1);
                    app.UIAxes7.cla;
                    hold(app.UIAxes7,'on');
                    plot(app.UIAxes7,app.ff(nb:ne,1),app.ff(nb:ne,5),'-b');
                    plot(app.UIAxes7,xfit,yfit,'r');
                    
                    
                case 'Fit4'
                    m1=min(app.f_inc(:,2));
                    app.f_inc(:,2)=app.f_inc(:,2)-m1;
                    app.ty(:)=app.ty(:)-app.fit2(2).*app.tx(:)-app.fit2(1)-exp(-(app.tx(:)+round(app.beg2*0.2)+app.fit2(4))/app.fit2(3));% substract
                    x0=[1 1];
                    options = optimoptions(@fminunc,'MaxFunEvals',5000);
                    lb = [0 -40000];
                    ub = [30000 40000];
                    [app.fit4,resnorm,residuals] = lsqcurvefit(@exp4fitfunUI,x0,app.tx',app.ty',lb,ub,options);
                    y1(:)=app.ty(:)-app.fit2(2).*app.tx(:)-app.fit2(1); % substract
                    y2(:)=app.fit2(2).*app.tx(:)+app.fit2(1);
                    %taue=app.fit4(3)+app.fit4(5)*app.tim(end);
                    s1=num2str(app.fit4(1));
                    s2=num2str(app.fit4(2));
                    % s3=num2str(app.fit4(3));
%                     s4=num2str(app.fit4(4));
%                     s5=num2str(app.tem);
%                     s6=num2str(resnorm);
%                     s7=num2str(app.fit4(5));
%                     s8=num2str(app.fit4(6));
                    ss1=['tau2 = ',s1];
                    ss2=['t1 = ',s2];
                    % ss3=['tau = ',s3];
%                     ss4=['t0 = ',s4];
%                     ss7=['tau2 = ',s7];
%                     ss8=['t1 = ',s8];
%                     ss5=['Temperature = ',s5];
%                     ss6=['Res Norm = ',s6];
                    %app.printfit.Value = {ss1;ss2;ss3;ss4;ss7;ss5;ss6;ss8};
                    app.printfit.Value = {ss1;ss2};
                    y=exp4fitfunUI(app.fit4,app.tx');
                    app.UIAxes3.cla;
                    hold(app.UIAxes3,'on');
                    plot(app.UIAxes3,app.tx,app.ty,'-b');
                    plot(app.UIAxes3,app.tx,y,'-r');
                    plot(app.UIAxes3,app.tx,y2,'--r');
                    title(app.UIAxes3, 'Fit4');
                    xlabel(app.UIAxes3, 'Time [sec]');
                    ylabel(app.UIAxes3, 'Q');
                    
                    app.UIAxes4.cla;
                    hold(app.UIAxes4,'on');
                    plot(app.UIAxes4,app.tx,app.ty);
                    plot(app.UIAxes4,app.tx,y1);
                    title(app.UIAxes4, ' Log of Fit1');
                    xlabel(app.UIAxes4, 'Time [sec]');
                    ylabel(app.UIAxes4, 'Q');
                    app.UIAxes5.cla;
                    plot(app.UIAxes5,1:length(residuals),residuals);
            end
        end

        % qends button pushed function
        function qendsButtonPushed(app)
            [c1,~]=size(app.c);
            y=zeros(c1,2);
            num_off=90;
            for ii=1:c1
                y(ii,1)=mean(app.ff1(app.c(ii,2)-num_off-50:app.c(ii,2)-num_off,1));
                y(ii,2)=mean(app.ff1(app.c(ii,2)-num_off-50:app.c(ii,2)-num_off,5));
            end
            app.poly_off=polyfit(y(:,1),y(:,2),app.polydegree.Value);
            %y1 = polyval(app.poly_off,y(:,1));
            y1 = polyval(app.poly_off,app.ff1(:,1));
            s1=num2str(app.poly_off(1));
            app.k_q=app.poly_off(1);
            st=['K_q = ',s1];
            app.textfork.Value = {st};
            app.UIAxes1.cla;
            hold(app.UIAxes1,'on');
            plot(app.UIAxes1,y(:,1),y(:,2),'-o');
            plot(app.UIAxes1,app.ff1(:,1),y1,'-');
            title(app.UIAxes1, ' Time dependence of Q at the end of the pulses');
            xlabel(app.UIAxes1, 'Time [sec]');
            ylabel(app.UIAxes1, 'Q');
           % ylim(app.UIAxes1,[-1 10]);
        end

        % clear_err button pushed function
        function clear_errButtonPushed(app)
            app.UIAxes6.cla;
        end

        % applypoly button pushed function
        function applypolyButtonPushed(app)
            y1 = polyval(app.poly_off,app.ff1(:,1));
            if  app.sfluid.Value == 0
                 app.ff1(:,5)=app.ff1(:,5)-y1(:);
            else
                app.ff1(:,5)=y1(:)-app.ff1(:,5);
            end
                 plot(app.UIAxes1,app.ff1(:,1),app.ff1(:,5),'--');
                 ylim(app.UIAxes1,[-1 3]);
            if app.polydegree.Value ~= 1
                 
                 app.k_q=0;
                 app.b_q=0;
                 
            else
                
                 app.k_q=y1(1);
                 app.b_q=y1(2);
                 
            end
        end

        % OpenFile button pushed function
        function OpenFileButtonPushed(app)
            runStartupFcn(app, @startupFcn);
        end

        % tempSF button pushed function
        function tempSFButtonPushed(app)
            yf=testL(app.ff1);
            
            app.UIAxes1.cla;
            hold(app.UIAxes1,'on');
            plot(app.UIAxes1,app.ff1(:,1),app.ff1(:,12),'.b');
            plot(app.UIAxes1,app.ff1(:,1),yf,'-r');
            title(app.UIAxes1, 'New temperature');
            xlabel(app.UIAxes1, 'Time [sec]');
            ylabel(app.UIAxes1, 'Temperature [mK]');
            ylim(app.UIAxes1,'auto');
            app.ff1(:,12)=yf(:);
        end

        % exportF button pushed function
        function exportFButtonPushed(app)
            y=exp2fitfunUI(app.fit2,app.tim');
            
            %        plot(app.UIAxes3,app.tim,app.af);
            %        plot(app.UIAxes3,app.tim,y);
            fileID = fopen('fitAndSub.dat','w');
            for ii=1:length(y)
                fprintf(fileID,'%f \t %f \t %f \n',app.tim(ii),app.af(ii),y(ii));
            end
            
            
            fclose(fileID);
        end

        % datafolder value changed function
        function datafolderValueChanged(app)
            app.datafolder.Value = {app.ruta};
        end

        % fit_act button pushed function
        function fit_actButtonPushed(app)
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            setAutoResize(app, app.UIFigure, true)
            app.TabGroup = uitabgroup(app.UIFigure);
            app.TabGroup.Units = 'pixels';
            app.prep_tab = uitab(app.TabGroup);
            app.prep_tab.Units = 'pixels';
            app.prep_tab.Title = 'Preparing';
            app.UIAxes1 = uiaxes(app.prep_tab);
            title(app.UIAxes1, 'Title');
            xlabel(app.UIAxes1, 'X');
            ylabel(app.UIAxes1, 'Y');
            app.init = uipanel(app.prep_tab);
            app.init.Title = 'Initial data manipulation';
            app.init.Units = 'pixels';
            app.LabelSlider = uilabel(app.init);
            app.LabelSlider.Text = 'Begin';
            app.cutBegin = uislider(app.init);
            app.cutBegin.Limits = [1 100];
            app.cutBegin.ValueChangedFcn = createCallbackFcn(app, @cutBeginValueChanged);
            app.cutBegin.Value = 1;
            app.Label = uilabel(app.init);
            app.Label.Text = 'End';
            app.cutEnd = uislider(app.init);
            app.cutEnd.ValueChangedFcn = createCallbackFcn(app, @cutEndValueChanged);
            app.setP = uibutton(app.init, 'push');
            app.setP.ButtonPushedFcn = createCallbackFcn(app, @setPButtonPushed);
            app.setP.Text = 'Set';
            app.findk = uibutton(app.init, 'push');
            app.findk.ButtonPushedFcn = createCallbackFcn(app, @findkButtonPushed);
            app.findk.Text = 'Find K''s';
            app.findpulse = uibutton(app.init, 'push');
            app.findpulse.ButtonPushedFcn = createCallbackFcn(app, @findpulseButtonPushed);
            app.findpulse.Text = 'Find pulses';
            app.qvstime = uibutton(app.init, 'push');
            app.qvstime.ButtonPushedFcn = createCallbackFcn(app, @qvstimeButtonPushed);
            app.qvstime.Text = 'SeeQ';
            app.pulsenumtext = uitextarea(app.init);
            app.qends = uibutton(app.init, 'push');
            app.qends.ButtonPushedFcn = createCallbackFcn(app, @qendsButtonPushed);
            app.qends.Text = 'Qends';
            app.LabelNumericEditField4 = uilabel(app.init);
            app.LabelNumericEditField4.Text = 'Poly degree';
            app.polydegree = uieditfield(app.init, 'numeric');
            app.polydegree.Limits = [1 10];
            app.polydegree.Value = 1;
            app.applypoly = uibutton(app.init, 'push');
            app.applypoly.ButtonPushedFcn = createCallbackFcn(app, @applypolyButtonPushed);
            app.applypoly.Text = 'Apply poly';
            app.OpenFile = uibutton(app.init, 'push');
            app.OpenFile.ButtonPushedFcn = createCallbackFcn(app, @OpenFileButtonPushed);
            app.OpenFile.Text = 'open file';
            app.LabelNumericEditField6 = uilabel(app.init);
            app.LabelNumericEditField6.Text = 'SFluid true?';
            app.sfluid = uieditfield(app.init, 'numeric');
            app.sfluid.Limits = [0 1];
            app.sfluid.ValueDisplayFormat = '%.0f';
            app.LabelTextArea = uilabel(app.prep_tab);
            app.LabelTextArea.Text = 'K values';
            app.textfork = uitextarea(app.prep_tab);
            app.textfork.Value = {'K_T = 0'; 'K_Q = 0'};
            app.LabelEditField = uilabel(app.prep_tab);
            app.LabelEditField.Text = 'Folder path';
            app.path = uieditfield(app.prep_tab, 'text');
            app.path.Value = 'e:\repos\MATLAB_gui_taus\';
            app.tempSF = uibutton(app.prep_tab, 'push');
            app.tempSF.ButtonPushedFcn = createCallbackFcn(app, @tempSFButtonPushed);
            app.tempSF.Text = 'Temperature at SO';
            app.filtering_tab = uitab(app.TabGroup);
            app.filtering_tab.Units = 'pixels';
            app.filtering_tab.Title = 'Filtering';
            app.UIAxes2 = uiaxes(app.filtering_tab);
            title(app.UIAxes2, 'Title');
            xlabel(app.UIAxes2, 'X');
            ylabel(app.UIAxes2, 'Y');
            app.Panel = uipanel(app.filtering_tab);
            app.Panel.Title = 'Pulse selection';
            app.Panel.Units = 'pixels';
            app.LabelNumericEditField = uilabel(app.Panel);
            app.LabelNumericEditField.Text = 'Pulse number';
            app.pulsenum = uieditfield(app.Panel, 'numeric');
            app.pulsenum.ValueChangedFcn = createCallbackFcn(app, @pulsenumValueChanged);
            app.pulsenum.Limits = [0 Inf];
            app.pulsenum.ValueDisplayFormat = '%.0f';
            app.LabelDropDown = uilabel(app.Panel);
            app.LabelDropDown.Text = 'Filters';
            app.filt = uidropdown(app.Panel);
            app.filt.Items = {'Median', 'Moving', 'None'};
            app.filt.ValueChangedFcn = createCallbackFcn(app, @filtValueChanged, true);
            app.filt.Value = 'Median';
            app.LabelNumericEditField2 = uilabel(app.Panel);
            app.LabelNumericEditField2.Text = '1st field filt';
            app.filt_n1 = uieditfield(app.Panel, 'numeric');
            app.filt_n1.ValueChangedFcn = createCallbackFcn(app, @filt_n1ValueChanged);
            app.filt_n1.Limits = [0 Inf];
            app.filt_n1.Value = 1;
            app.apply1 = uibutton(app.Panel, 'push');
            app.apply1.ButtonPushedFcn = createCallbackFcn(app, @apply1ButtonPushed);
            app.apply1.Text = 'Apply filter';
            app.incline = uibutton(app.Panel, 'push');
            app.incline.ButtonPushedFcn = createCallbackFcn(app, @inclineButtonPushed);
            app.incline.Text = 'Incline';
            app.LabelSlider2 = uilabel(app.Panel);
            app.LabelSlider2.Text = 'Begin';
            app.cutBegin2 = uislider(app.Panel);
            app.cutBegin2.Limits = [1 100];
            app.cutBegin2.ValueChangedFcn = createCallbackFcn(app, @cutBegin2ValueChanged);
            app.cutBegin2.Value = 1;
            app.Label2 = uilabel(app.Panel);
            app.Label2.Text = 'End';
            app.cutEnd2 = uislider(app.Panel);
            app.cutEnd2.Limits = [1 100];
            app.cutEnd2.ValueChangedFcn = createCallbackFcn(app, @cutEnd2ValueChanged);
            app.cutEnd2.Value = 1;
            app.apply_cut = uibutton(app.Panel, 'push');
            app.apply_cut.ButtonPushedFcn = createCallbackFcn(app, @apply_cutButtonPushed);
            app.apply_cut.Text = 'Apply cut';
            app.LabelTextArea2 = uilabel(app.filtering_tab);
            app.LabelTextArea2.Text = 'Data folder';
            app.datafolder = uitextarea(app.filtering_tab);
            app.datafolder.ValueChangedFcn = createCallbackFcn(app, @datafolderValueChanged);
            app.datafolder.Value = {'path'};
            app.fitting_tab = uitab(app.TabGroup);
            app.fitting_tab.Units = 'pixels';
            app.fitting_tab.Title = 'Fitting';
            app.UIAxes3 = uiaxes(app.fitting_tab);
            title(app.UIAxes3, 'Title');
            xlabel(app.UIAxes3, 'X');
            ylabel(app.UIAxes3, 'Y');
            app.Panel2 = uipanel(app.fitting_tab);
            app.Panel2.Title = 'Filter tools';
            app.Panel2.Units = 'pixels';
            app.LabelDropDown2 = uilabel(app.Panel2);
            app.LabelDropDown2.Text = 'Fitting model';
            app.fitfuns = uidropdown(app.Panel2);
            app.fitfuns.Items = {'Fit1', 'Fit2', 'Fit3', 'Fit4', 'None'};
            app.fitfuns.ValueChangedFcn = createCallbackFcn(app, @fitfunsValueChanged, true);
            app.fitfuns.Value = 'Fit1';
            app.Label3 = uilabel(app.Panel2);
            app.Label3.Text = 'Fit 1: A*exp(-t+t0/tau) + B';
            app.Label4 = uilabel(app.Panel2);
            app.Label4.Text = 'Fit 2: A + b*t + exp(-t+t0/tau)';
            app.Label5 = uilabel(app.Panel2);
            app.Label5.Text = 'Fit 3: A + b*t + exp(-t+t0/(tau+C*t))';
            app.exportF = uibutton(app.Panel2, 'push');
            app.exportF.ButtonPushedFcn = createCallbackFcn(app, @exportFButtonPushed);
            app.exportF.Text = 'Export graphs';
            app.UIAxes4 = uiaxes(app.fitting_tab);
            title(app.UIAxes4, 'Title');
            xlabel(app.UIAxes4, 'X');
            ylabel(app.UIAxes4, 'Y');
            app.Panel3 = uipanel(app.fitting_tab);
            app.Panel3.Title = 'Fitting parameters';
            app.Panel3.Units = 'pixels';
            app.printfit = uitextarea(app.Panel3);
            app.printfit.Value = {'1'; '2'; '3'; '4'; '5'; '6'; '7'; '8'};
            app.resid_tab = uitab(app.TabGroup);
            app.resid_tab.Units = 'pixels';
            app.resid_tab.Title = 'Residuals';
            app.UIAxes5 = uiaxes(app.resid_tab);
            title(app.UIAxes5, 'Residuals');
            xlabel(app.UIAxes5, 'Time');
            ylabel(app.UIAxes5, 'Deviation');
            app.UIAxes6 = uiaxes(app.resid_tab);
            title(app.UIAxes6, 'Title');
            xlabel(app.UIAxes6, 'X');
            ylabel(app.UIAxes6, 'Y');
            app.errtext = uitextarea(app.resid_tab);
            app.errtext.Value = {'res_n'; 'chi'};
            app.LabelNumericEditField3 = uilabel(app.resid_tab);
            app.LabelNumericEditField3.Text = 'Bins';
            app.nbin = uieditfield(app.resid_tab, 'numeric');
            app.nbin.Limits = [1 Inf];
            app.nbin.Value = 21;
            app.clear_err = uibutton(app.resid_tab, 'push');
            app.clear_err.ButtonPushedFcn = createCallbackFcn(app, @clear_errButtonPushed);
            app.clear_err.Text = 'Clear';
            app.Closeapp = uibutton(app.resid_tab, 'push');
            app.Closeapp.ButtonPushedFcn = createCallbackFcn(app, @CloseappButtonPushed);
            app.Closeapp.Text = 'Close';
            app.LabelNumericEditField5 = uilabel(app.resid_tab);
            app.LabelNumericEditField5.Text = 'Edit Field';
            app.k_f2 = uieditfield(app.resid_tab, 'numeric');
            app.k_f2.Value = 0.0002;
            app.remodell_tab = uitab(app.TabGroup);
            app.remodell_tab.Units = 'pixels';
            app.remodell_tab.Title = 'Re-model';
            app.UIAxes7 = uiaxes(app.remodell_tab);
            title(app.UIAxes7, 'Re-model');
            xlabel(app.UIAxes7, 'Original time');
            ylabel(app.UIAxes7, 'Original Q');
        end
    end

    methods (Access = public)

        % Construct app
        function app = analysisAD()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end